classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ResetButton                 matlab.ui.control.Button
        Select3DobjecttoloadButton  matlab.ui.control.Button
        Volumecm3EditField          matlab.ui.control.NumericEditField
        Volumecm3Label              matlab.ui.control.Label
        Volumem3EditField           matlab.ui.control.NumericEditField
        Volumem3Label               matlab.ui.control.Label
        NoofshotsEditField          matlab.ui.control.NumericEditField
        NoofshotsEditFieldLabel     matlab.ui.control.Label
        RunMonteCarloondefaultobjectButton  matlab.ui.control.Button
        RunMonteCarloButton         matlab.ui.control.Button
        RunRectangularIntegrationButton  matlab.ui.control.Button
        Load3DModelFromFileButton   matlab.ui.control.Button
        VolumeMeasurementLabel      matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        obj % 3D File to process
        F
        V
        N
        FV
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = "Volume Measurement - Dominik Ciesiołkiewicz & Alicja Krystofiak";
        end

        % Button pushed function: Load3DModelFromFileButton
        function Load3DModelFromFileButtonPushed(app, event)
           %%OBJ
%             obj = readObj("315.obj");
%             obj = app.obj;
%             disp("File read successfully.");
% 
%             p = obj.v;
%             t = obj.f.v;
            
%             tris = trisurf(t,p(:,1),p(:,2),p(:,3),0,'Parent', app.UIAxes);
%             direction = [1 0 0];
%             rotate(tris,direction,90)

            %%STL
            p = patch(app.UIAxes, 'faces', app.F, 'vertices', app.V);
            set(p, 'FaceVertexCData', app.N); %%co to robi?
            set(p, 'facealpha', .1)
            set(p, 'EdgeColor', [.8 .8 .8]);
            set(p, 'LineWidth', .01);

            view(app.UIAxes, 3)
            hold(app.UIAxes,'on')

            set(app.RunMonteCarloButton, 'Enable', 'on');
            set(app.RunMonteCarloondefaultobjectButton, 'Enable', 'off');
        end

        % Button pushed function: RunMonteCarloondefaultobjectButton
        function RunMonteCarloondefaultobjectButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Measuring...','Indeterminate','on');
            drawnow
            
            app.obj = readObj("315.obj");
            %disp("File read successfully.");

            p = app.obj.v;
            t = app.obj.f.v;
            
            tris = trisurf(t,p(:,1),p(:,2),p(:,3),0,'Parent', app.UIAxes, 'FaceAlpha', 0.1);
            direction = [1 0 0];
            rotate(tris,direction,90)
            hold(app.UIAxes,'on')

            x1 = 0; %lewa sciana
            x2 = 1150; %lewa wneka drzwi
            x3 = 2230; %prawa wneka drzwi
            x4 = 3100; %lewa sciana srodkowej wneki
            x5 = 5070; %prawa sciana srodkowej wneki
            x6 = 7470; %lewa strona malej wneki
            x7 = 7990; %prawa sciana
        
            y1 = 0; %wneka drzwi
            y2 = 200;%dolna sciana
            y3 = 290; %wneka mala z prawej
            y4 = 2430; %poczatek skosu
            y5 = 3280; %koniec skosu / gorna sciana / poczatek srodkowej wneki
            y6 = 4600; %koniec srodkowej wneki
        
            z1 = 0; %podloga
            z2 = 1850; %poczatek skosu
            z3 = 1870; %sufit wneki
            z4 = 2600; %sufit
            
            trafienia = 0;
            strzaly = app.NoofshotsEditField.Value;
            obj_obszaru = x7*y6*z4;
            
            %losowanie punktu
            X = (rand(1,strzaly))*x7;
            Y = (rand(1,strzaly))*y6;
            Z = (rand(1,strzaly))*z4;
            
            for i = 1:strzaly
                %wneka drzwiowa
                if (X(i) >= x2 && X(i) <=x3 && Y(i) >=y1 && Y(i) <= y2 && Z(i) >= z1 && Z(i) <= z4)
                    trafienia = trafienia + 1;
                    plot3(app.UIAxes, X(i),Y(i)-1000,Z(i)-4000, '*b');
                %mala wneka
                elseif (X(i) >= x1 && X(i) <=x6 && Y(i) >=y2 && Y(i) <= y3 && Z(i) >= z1 && Z(i) <= z4)
                    trafienia = trafienia + 1;
                    plot3(app.UIAxes, X(i),Y(i)-1000,Z(i)-4000, '*b');
                %sala do sufitu / poczatku skosu
                elseif (X(i) >= x1 && X(i) <=x7 && Y(i) >=y3 && Y(i) <= y4 && Z(i) >= z1 && Z(i) <= z4)
                    trafienia = trafienia + 1;
                    plot3(app.UIAxes, X(i),Y(i)-1000,Z(i)-4000,'*b');
                %skos
                elseif (X(i) >= x1 && X(i) <=x7 && Y(i) >=y4 && Y(i) <= y5 && Z(i) >= z1 &&  Z(i) <= interp2([x7 0], [y4 y5], [z4 z4; z2 z2], X(i), Y(i), 'linear'))
                    trafienia = trafienia + 1;
                    plot3(app.UIAxes, X(i),Y(i)-1000,Z(i)-4000, '*b');
                %wneka srodkowa
                elseif (X(i) >= x4 && X(i) <=x5 && Y(i) >=y5 && Y(i) <= y6 && Z(i) >= z1 && Z(i) <= z3)
                    trafienia = trafienia + 1;
                    plot3(app.UIAxes, X(i),Y(i)-1000,Z(i)-4000,'*b');
                else
                    %nietrafione
                    plot3(app.UIAxes, X(i),Y(i)-1000,Z(i)-4000, '*r');
                end
            end
            %trafienia
            objetosc = trafienia / strzaly * obj_obszaru;
            %disp("Objętość bryły: " + objetosc + "mm3; " + objetosc/1000000000 + "m3");
            app.Volumecm3EditField.Value = objetosc;
            app.Volumem3EditField.Value = objetosc/1000000000;
            set(app.Load3DModelFromFileButton, 'Enable', 'off');
            set(app.RunMonteCarloondefaultobjectButton, 'Enable', 'off');
            close(d)
        end

        % Button pushed function: Select3DobjecttoloadButton
        function Select3DobjecttoloadButtonPushed(app, event)
            [file,path] = uigetfile('*.stl');
            if isequal(file,0)
               %disp('User selected Cancel');
            else
               %disp(['User selected ', fullfile(path,file)]);
               %app.obj = readObj(fullfile(path,file));
               
               %%STL
               [app.F, app.V, app.N] = stlread(fullfile(path,file));
               app.FV = stlread(fullfile(path,file)); 

               %%OBJ
               %set(app.RunMonteCarloButton, 'Enable', 'on');
               
               set(app.Load3DModelFromFileButton, 'Enable', 'on');
            end

        end

        % Callback function
        function TestButtonPushed(app, event)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Volumecm3EditField.Value = 0;
            app.Volumem3EditField.Value = 0;
            set(app.RunMonteCarloButton, 'Enable', 'off');
            set(app.RunMonteCarloondefaultobjectButton, 'Enable', 'on');
            set(app.Load3DModelFromFileButton, 'Enable', 'on');
            cla(app.UIAxes);
        end

        % Button pushed function: RunMonteCarloButton
        function RunMonteCarloButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Measuring...','Indeterminate','on');
            drawnow
            noOfShots = app.NoofshotsEditField.Value;

            maxV = max(app.V);
            minV = min(app.V);
            
            widthX = abs(maxV(1)) + abs(minV(1));
            widthY = abs(maxV(2)) + abs(minV(2));
            widthZ = abs(maxV(3)) + abs(minV(3));
            areaVolume = widthX * widthY * widthZ;

            X = minV(1) + (maxV(1)-minV(1)).*rand(noOfShots,1);
            Y = minV(2) + (maxV(2)-minV(2)).*rand(noOfShots,1);
            Z = minV(3) + (maxV(3)-minV(3)).*rand(noOfShots,1);

            points = [X Y Z];
            in = inpolyhedron(app.FV, points);

            plot3(app.UIAxes, points(~in, 1), points(~in, 2), points(~in, 3), '*r');
            plot3(app.UIAxes, points(in, 1), points(in, 2), points(in, 3), '*b');

            inPercentage = size(find(in == 1), 1)/noOfShots;
            vol = inPercentage * areaVolume;

            app.Volumecm3EditField.Value = vol;
            app.Volumem3EditField.Value = vol/1000000000;
            set(app.RunMonteCarloButton, 'Enable', 'off');
            close(d)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.VolumeMeasurementLabel = uilabel(app.LeftPanel);
            app.VolumeMeasurementLabel.Text = 'Volume Measurement';
            app.Load3DModelFromFileButton = uibutton(app.LeftPanel, 'push');
            app.Load3DModelFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @Load3DModelFromFileButtonPushed, true);
            app.Load3DModelFromFileButton.Enable = 'off';
            app.Load3DModelFromFileButton.Text = 'Load 3D Model From File';
            app.RunRectangularIntegrationButton = uibutton(app.LeftPanel, 'push');
            app.RunRectangularIntegrationButton.Enable = 'off';
            app.RunRectangularIntegrationButton.Text = 'Run Rectangular Integration ';
            app.RunMonteCarloButton = uibutton(app.LeftPanel, 'push');
            app.RunMonteCarloButton.ButtonPushedFcn = createCallbackFcn(app, @RunMonteCarloButtonPushed, true);
            app.RunMonteCarloButton.Enable = 'off';
            app.RunMonteCarloButton.Text = 'Run Monte Carlo';
            app.RunMonteCarloondefaultobjectButton = uibutton(app.LeftPanel, 'push');
            app.RunMonteCarloondefaultobjectButton.ButtonPushedFcn = createCallbackFcn(app, @RunMonteCarloondefaultobjectButtonPushed, true);
            app.RunMonteCarloondefaultobjectButton.Text = 'Run Monte Carlo on default object';
            app.NoofshotsEditFieldLabel = uilabel(app.LeftPanel);
            app.NoofshotsEditFieldLabel.Text = 'No. of shots:';
            app.NoofshotsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NoofshotsEditField.Value = 1000;
            app.Volumem3Label = uilabel(app.LeftPanel);
            app.Volumem3Label.Text = 'Volume (m3):';
            app.Volumem3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Volumem3EditField.Editable = 'off';
            app.Volumecm3Label = uilabel(app.LeftPanel);
            app.Volumecm3Label.Text = 'Volume (cm3):';
            app.Volumecm3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Volumecm3EditField.Editable = 'off';
            app.Select3DobjecttoloadButton = uibutton(app.LeftPanel, 'push');
            app.Select3DobjecttoloadButton.ButtonPushedFcn = createCallbackFcn(app, @Select3DobjecttoloadButtonPushed, true);
            app.Select3DobjecttoloadButton.Text = 'Select 3D object to load';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Visualisation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end